classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        label                          matlab.ui.control.Label
        Button_9                       matlab.ui.control.Button
        FGreverse                      matlab.ui.control.CheckBox
        Label_16                       matlab.ui.control.Label
        ImgFGbin                       matlab.ui.control.Image
        LabelRSInputSize               matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        Original                       matlab.ui.control.Image
        CenterPanel                    matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        Tab_3                          matlab.ui.container.Tab
        TabGroup4                      matlab.ui.container.TabGroup
        Tab_10                         matlab.ui.container.Tab
        UIAxesGrayHistogramHistgram    matlab.ui.control.UIAxes
        Tab_11                         matlab.ui.container.Tab
        UIAxesGrayHistogramHistgram_2  matlab.ui.control.UIAxes
        Tab_12                         matlab.ui.container.Tab
        Tab_4                          matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        Tab_7                          matlab.ui.container.Tab
        SliderRSk_y                    matlab.ui.control.Slider
        k_yLabel                       matlab.ui.control.Label
        SliderRSk_x                    matlab.ui.control.Slider
        k_xSliderLabel                 matlab.ui.control.Label
        LabelRSScalek                  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        Tab_9                          matlab.ui.container.Tab
        Button_2                       matlab.ui.control.Button
        KnobRSrotate                   matlab.ui.control.Knob
        Label_2                        matlab.ui.control.Label
        Tab_13                         matlab.ui.container.Tab
        TabGroup7                      matlab.ui.container.TabGroup
        Tab_14                         matlab.ui.container.Tab
        SpinnerEnhanceTan              matlab.ui.control.Spinner
        tanSpinnerLabel                matlab.ui.control.Label
        Button_3                       matlab.ui.control.Button
        gxytanalphacdotfxyLabel        matlab.ui.control.Label
        Tab_15                         matlab.ui.container.Tab
        Button_4                       matlab.ui.control.Button
        SpinnerEnhanceLog              matlab.ui.control.Spinner
        cLabel                         matlab.ui.control.Label
        gxyccdotlogfxy1Label           matlab.ui.control.Label
        Tab_16                         matlab.ui.container.Tab
        SpinnerEnhanceExpa             matlab.ui.control.Spinner
        cLabel_4                       matlab.ui.control.Label
        SpinnerEnhanceExpc             matlab.ui.control.Spinner
        cLabel_3                       matlab.ui.control.Label
        SpinnerEnhanceExpb             matlab.ui.control.Spinner
        cLabel_2                       matlab.ui.control.Label
        BtnEnhanceExp                  matlab.ui.control.Button
        gxybccdotfxyalpha1Label        matlab.ui.control.Label
        Tab_5                          matlab.ui.container.Tab
        Slider                         matlab.ui.control.Slider
        Label_4                        matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        Tab_17                         matlab.ui.container.Tab
        Label_14                       matlab.ui.control.Label
        TabGroup8                      matlab.ui.container.TabGroup
        Tab_20                         matlab.ui.container.Tab
        BtnFGOstu                      matlab.ui.control.Button
        OtsuLabel                      matlab.ui.control.Label
        LabelFGOstu                    matlab.ui.control.Label
        Tab_21                         matlab.ui.container.Tab
        KnobFGt                        matlab.ui.control.Knob
        ThresholdLabel                 matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        LabelFGt                       matlab.ui.control.Label
        kTab                           matlab.ui.container.Tab
        SpinnerFGk                     matlab.ui.control.Spinner
        KSpinnerLabel                  matlab.ui.control.Label
        Button_8                       matlab.ui.control.Button
        Tab_22                         matlab.ui.container.Tab
        KnobFGseed                     matlab.ui.control.Knob
        Btnseed                        matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Tab_6                          matlab.ui.container.Tab
        TabGroup6                      matlab.ui.container.TabGroup
        LBPTab                         matlab.ui.container.Tab
        LBPButton                      matlab.ui.control.Button
        SpinnerNFgVar_3                matlab.ui.control.Spinner
        Label_10                       matlab.ui.control.Label
        SpinnerNFgVar_2                matlab.ui.control.Spinner
        Label_9                        matlab.ui.control.Label
        HOGTab                         matlab.ui.container.Tab
        SpinnerNFgVar_6                matlab.ui.control.Spinner
        Label_13                       matlab.ui.control.Label
        SpinnerNFgVar_5                matlab.ui.control.Spinner
        Label_12                       matlab.ui.control.Label
        SpinnerNFgVar_4                matlab.ui.control.Spinner
        Label_11                       matlab.ui.control.Label
        HOGButton                      matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        Tab                            matlab.ui.container.Tab
        Button_5                       matlab.ui.control.Button
        SliderNFsaltD                  matlab.ui.control.Slider
        Label_5                        matlab.ui.control.Label
        Tab_2                          matlab.ui.container.Tab
        SpinnerNFgVar                  matlab.ui.control.Spinner
        Label_7                        matlab.ui.control.Label
        SliderNFgm                     matlab.ui.control.Slider
        Label_6                        matlab.ui.control.Label
        BtnNFg                         matlab.ui.control.Button
        Tab_18                         matlab.ui.container.Tab
        Button_6                       matlab.ui.control.Button
        sigma_rKnobLabel               matlab.ui.control.Label
        sigma_sKnobLabel               matlab.ui.control.Label
        omegaLabel                     matlab.ui.control.Label
        KnobNFsigma_r                  matlab.ui.control.Knob
        KnobNFsigma_s                  matlab.ui.control.Knob
        omegasigma_ssigma_rLabel       matlab.ui.control.Label
        SliderNFomega                  matlab.ui.control.Slider
        Tab_19                         matlab.ui.container.Tab
        NKnobNFmohu                    matlab.ui.control.DiscreteKnob
        NKnobLabel                     matlab.ui.control.Label
        Button_7                       matlab.ui.control.Button
        Label_8                        matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        LabelRSInputSize_3             matlab.ui.control.Label
        LabelRSInputSize_2             matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    methods (Access = public)


        function [histogram, lbp_image] = lbp_histogram(app,image, radius, neighbors)
            % 计算LBP特征直方图
            [height, width] = size(image);
            lbp_image = zeros(height, width);  % 初始化LBP图像
            histogram = zeros(2^neighbors, 1);  % LBP模式的总数是 2^neighbors
        
            for x = 1 + radius : width - radius
                for y = 1 + radius : height - radius
                    % 获取邻域像素
                    neighborhood = getNeighborhood(app,image, x, y, radius, neighbors);
                    % 计算LBP值
                    lbp_value = calculateLBP(app,neighborhood);
                    % 计算直方图
                    lbp_image(y, x) = lbp_value;
                    histogram(lbp_value + 1) = histogram(lbp_value + 1) + 1;
                end
            end
        end
        
        function neighborhood = getNeighborhood(~,image, x, y, radius, neighbors)
            % 获取邻域的像素值
            angle_step = 2 * pi / neighbors;
            neighborhood = zeros(1, neighbors);
            
            for i = 1:neighbors
                angle = angle_step * (i - 1);
                offset_x = round(radius * cos(angle));
                offset_y = round(radius * sin(angle));
                neighborhood(i) = image(y + offset_y, x + offset_x);
            end
        end
        
        function lbp_value = calculateLBP(~,neighborhood)
            % 计算LBP值，返回一个整数值
            center_pixel = neighborhood(1);
            lbp_value = 0;
        
            for i = 2:length(neighborhood)
                if neighborhood(i) >= center_pixel
                    lbp_value = lbp_value + 2^(i - 2);
                end
            end
        end
        
        function histogram = gray_histogram(~, image)

            histogram = zeros(256, 1);
            [height, width] = size(image);

            for x = 1:width
                for y = 1:height
                    pixelValue = image(y, x) + 1;
                    histogram(pixelValue) = histogram(pixelValue) + 1;
                end
            end
        end


        % 计算HOG特征
        function [hog_features, hog_image] = hog_feature(app,image, cell_size, block_size, bins)
        
            % 将输入图像转换为双精度
            image = double(image);
            
            % 计算梯度（水平和垂直方向）
            [Gx, Gy] = imgradientxy(image);
            
            % 计算每个像素的梯度幅度和方向
            [mag, ang] = imgradient(Gx, Gy);
            
            % 将图像分成小块（cell）
            [height, width] = size(image);
            num_cells_x = floor(width / cell_size);
            num_cells_y = floor(height / cell_size);
            
            % 初始化HOG图像和特征向量
            hog_image = zeros(height, width);
            hog_features = [];
            
            % 计算每个cell的梯度直方图
            for i = 1:num_cells_y
                for j = 1:num_cells_x
                    % 获取cell区域
                    cell_x_start = (j-1) * cell_size + 1;
                    cell_x_end = j * cell_size;
                    cell_y_start = (i-1) * cell_size + 1;
                    cell_y_end = i * cell_size;
                    
                    % 获取cell内的梯度方向和幅度
                    cell_mag = mag(cell_y_start:cell_y_end, cell_x_start:cell_x_end);
                    cell_ang = ang(cell_y_start:cell_y_end, cell_x_start:cell_x_end);
                    
                    % 计算方向梯度直方图
                    hog_hist = compute_histogram(app,cell_mag, cell_ang, bins);
                    
                    % 将每个cell的HOG特征存储到特征向量中
                    hog_features = [hog_features, hog_hist];
                    
                    % 可视化：构建HOG图像
                    for x = 1:cell_size
                        for y = 1:cell_size
                            angle = cell_ang(y, x);
                            magnitude = cell_mag(y, x);
                            % 将梯度可视化为箭头
                            hog_image(cell_y_start + y - 1, cell_x_start + x - 1) = magnitude;
                        end
                    end
                end
            end
            
            % 归一化HOG特征
            hog_features = normalize_hog(app,hog_features, block_size, num_cells_x, num_cells_y);
        
        end

        % 计算方向梯度直方图
        function hist = compute_histogram(~,mag, ang, bins)
            % 将角度转换到0-180度区间
            ang = mod(ang, 180);
            
            % 创建方向梯度直方图
            hist = zeros(1, bins);
            
            % 计算每个像素的梯度方向对应的bin
            for i = 1:numel(ang)
                bin = floor(ang(i) / (180 / bins)) + 1;
                hist(bin) = hist(bin) + mag(i);
            end
        end
        
        % 归一化HOG特征
        function hog_features = normalize_hog(~,hog_features, block_size, num_cells_x, num_cells_y)
            % 归一化块（block）
            num_blocks_x = num_cells_x - block_size + 1;
            num_blocks_y = num_cells_y - block_size + 1;
            normalized_hog_features = [];
            
            for i = 1:num_blocks_y
                for j = 1:num_blocks_x
                    block_start_idx = (i-1) * num_cells_x + j;
                    block_end_idx = block_start_idx + block_size * block_size - 1;
                    block = hog_features(block_start_idx:block_end_idx);
                    
                    % L2 归一化
                    norm_factor = norm(block, 2);
                    if norm_factor > 0
                        block = block / norm_factor;
                    end
                    
                    normalized_hog_features = [normalized_hog_features, block];
                end
            end
            
            hog_features = normalized_hog_features;
        end


        % 绘制灰度直方图数组到坐标轴
        function draw_histogram(app, histogram)
            % 获取灰度直方图坐标轴组件
            axesHandle = app.UIAxesGrayHistogramHistgram;

            % 绘制直方图
            bar(axesHandle, histogram);

        end

        % 绘制灰度均衡化直方图数组到坐标轴
        function draw_histogram_2(app, histogram)
            % 获取灰度直方图坐标轴组件
            axesHandle = app.UIAxesGrayHistogramHistgram_2;

            % 绘制直方图
            bar(axesHandle, histogram);

        end


        % 直方图均衡化
        function equalized_image  = histogram_equalization(~, image)

            histgram = imhist(image);
            [h, w] = size(image);
            equalized_image = zeros(h, w, 'uint8'); % 初始化为uint8图像
            s = zeros(256); s(1) = histgram(1);

            for t = 2 : 256
                s(t) = s(t - 1) + histgram(t);
            end

            for x = 1: w
                for y = 1: h
                    equalized_image(y, x) = uint8(255 * s(image(y, x) + 1) / (w * h));

                end
            end
            equalized_image = uint8(equalized_image);

        end
         % 线性灰度级增强
        function new_image = linear_enhance(~, image, tan_alpha)
            % 对每个像素应用线性变换
            new_image = tan_alpha * double(image);
            new_image = uint8(max(0, min(255, new_image)));
        end

        % 非线性对数变换
        function new_image = log_enhance(~, image, c)
            new_image = c * log(1 + double(image));
            new_image = uint8(max(0, min(255, new_image)));
        end


        % 非线性指数变换
        function new_image = exp_enhance(~, image, a, b, c)
            new_image = power(b, c * (image - a)) - 1;
            new_image = uint8(max(0, min(255, new_image)));
        end

        % 图像缩放
        function new_image = scale(~, image, k_x, k_y)
            [h, w, ~] = size(image);
            new_h = round(h * k_y); % 更改画布大小
            new_w = round(w * k_x);

            new_image = zeros(new_h, new_w, 3);  % Initialize the output image with 3 channels for RGB.

            for x_ = 1:new_w
                for y_ = 1:new_h
                    x = x_ / k_x;
                    y = y_ / k_y;
                    a = x - floor(x);
                    b = y - floor(y);
                    x = floor(x);
                    y = floor(y);

                    x = max(1, min(w - 1, x));
                    y = max(1, min(h - 1, y));

                    f_x_y_b = image(y, x, :) + b * (image(y + 1, x, :) - image(y, x, :));
                    f_x_1_y_b = image(y, x + 1, :) + b * (image(y + 1, x + 1, :) - image(y, x + 1, :));
                    f_x_a_y_b = f_x_y_b + a * (f_x_1_y_b - f_x_y_b);
                    new_image(y_, x_, :) = f_x_a_y_b;
                end
            end

            new_image = uint8(new_image);  % 转换为 uint8 类型以便正确显示
        end

        % 图像旋转
        function rotated_image = rotate(~, image, theta)
            [h, w, ~] = size(image);

            % 计算旋转后的图像大小，round四舍五入确保是整数
            new_h = round(h * abs(cosd(theta)) + w * abs(sind(theta)));
            new_w = round(h * abs(sind(theta)) + w * abs(cosd(theta)));

            rotated_image = zeros(new_h, new_w, 3);

            % 计算旋转中心
            center_x = w / 2;
            center_y = h / 2;

            for x_ = 1:new_w
                for y_ = 1:new_h
                    x = x_ - center_x;
                    y = y_ - center_y;

                    % 根据逆变换公式，计算旋转后的坐标
                    % 这是在原图中的位置
                    new_x = cosd(theta) * x - sind(theta) * y + center_x;
                    new_y = sind(theta) * x + cosd(theta) * y + center_y;

                    % 使用双线性插值估算颜色值
                    a = new_x - floor(new_x);
                    b = new_y - floor(new_y);
                    x1 = max(1, min(w - 1, floor(new_x)));
                    y1 = max(1, min(h - 1, floor(new_y)));
                    x2 = min(x1 + 1, w);
                    y2 = min(y1 + 1, h);

                    f_x_y_b = image(y1, x1, :) + b * (image(y2, x1, :) - image(y1, x1, :));
                    f_x_1_y_b = image(y1, x2, :) + b * (image(y2, x2, :) - image(y1, x2, :));
                    f_x_a_y_b = f_x_y_b + a * (f_x_1_y_b - f_x_y_b);
                    rotated_image(y_, x_, :) = f_x_a_y_b;
                end
            end

            rotated_image = uint8(rotated_image);
        end

        % 双边滤波
        function filter_image = filter_double(~, image, w, sigma_s, sigma_r)
            image = im2double(image);


            [X, Y] = meshgrid(-w:w, -w:w);

            % 计算邻域内的空间权值
            Gs = exp(-(X.^2 + Y.^2) / (2 * sigma_s^2));


            dim = size(image);
            filter_image = zeros(dim);

            for i = 1:dim(1)
                for j = 1:dim(2)
                    % 确定作用区域
                    iMin = max(i - w, 1);
                    iMax = min(i + w, dim(1));
                    jMin = max(j - w, 1);
                    jMax = min(j + w, dim(2));

                    I = image(iMin:iMax, jMin:jMax, :); % 提取该区域的源图像值赋给 I

                    % 计算灰度临近权值
                    Gr = exp(-(sum((I - image(i, j, :)).^2, 3)) / (2 * sigma_r^2));

                    % W 为Gr 和 G的乘积
                    W = Gr .* Gs((iMin:iMax) - i + w + 1, (jMin:jMax) - j + w + 1);


                    filter_image(i, j, :) = sum(sum(W .* I, 1), 2) / sum(W(:));
                end
            end
        end


        % 基于模糊技术的加权均值滤波
        function filter_image = filter_mohu(~, image, N)
            % N = N;
            [m, n] = size(image);
            image = double(image);
            filter_image = image;
            % 计算权重的分母
            sNum = N^2 - 1;

            % 计算卷积核的半径
            N = (N - 1) / 2;

            % 遍历图像的像素
            for i = 1 + N : m - N
                for j = 1 + N : n - N
                    sum = 0;

                    % 计算局部像素差的平方和
                    for m = i - N : i + N
                        for n = j - N : j + N
                            d = (image(i, j) - image(m, n))^2;
                            sum = sum + d;
                        end
                    end

                    % 计算模糊参数 beta
                    beta = sum / sNum;
                    sum2 = 0;
                    sum3 = 0;

                    % 应用加权均值滤波
                    for m = i - N : i + N
                        for n = j - N : j + N
                            d = (image(i, j) - image(m, n))^2;
                            % 计算权重
                            mu = exp(-d / beta);
                            % 加权均值的分子
                            sum2 = sum2 + mu / beta * image(m, n);
                            % 加权均值的分母
                            sum3 = sum3 + mu / beta;
                        end
                    end

                    % 更新结果图像的像素值
                    filter_image(i, j) = sum2 / sum3;
                end
            end

            % 将结果图像转换为8位无符号整数型
            filter_image = uint8(filter_image);
        end


        % 边缘检测，得到二值边界图像（或者拉普拉斯滤波）
        function edgeImage = get_edge(~, image, op)
            image = im2double(rgb2gray(image));
            if strcmp(op, 'laplacian')
                edgeImage = abs(imfilter(image, fspecial('laplacian', 0)));
            else
                edgeImage = edge(image, op);    % 使用roberts算子进行边缘检测，得到二值边界图像
            end
        end


        % 图像锐化
        function sharpImage = sharpen_image(~, image, op)
            image = im2double(image);

            if strcmp(op, 'roberts')
                H1 = [1 0; 0 -1];
                H2 = [0 1; -1 0]; % roberts算子模板
            elseif strcmp(op, 'sobel')
                H1 = [-1 -2 -1; 0 0 0; 1 2 1];
                H2 = [-1 0 1; -2 0 2; -1 0 1]; % sobel算子模板
            elseif strcmp(op, 'prewitt')
                H1 = [-1 -1 -1; 0 0 0; 1 1 1];
                H2 = [-1 0 1; -1 0 1; -1 0 1]; % prewitt算子模板
            elseif strcmp(op, 'laplacian')
                %H = fspecial('laplacian', 0);   % 生成Laplacian模板
                H = [0 -1 0; -1 5 -1; 0 -1 0];  % Laplacian 锐化模板
                sharpImage = imfilter(image, H);
                return
            end

            R1 = imfilter(image, H1);
            R2 = imfilter(image, H2);
            edgeImage = abs(R1) + abs(R2); % 基于模板运算，得到梯度图像
            sharpImage = image + edgeImage; % 锐化图像
        end

        function new_image = edge_roberts(~, f, T)

            [m, n] = size(f);
            new_image = zeros(m, n);

            for i = 2:m-1
                for j = 2:n-1
                    new_image(i,j)=abs(f(i+1,j+1)-f(i,j))+abs(f(i,j+1)-f(i+1,j));
                    if new_image(i,j)<T
                        new_image(i,j)=0;
                    else
                        new_image(i,j)=255;
                    end
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = edge_prewitt(~, f, T)
            [m, n] = size(f);
            new_image = zeros(m, n);

            for i = 2:m-1
                for j = 2:n-1

                    new_image(i,j)=abs(f(i-1,j-1)+f(i,j-1)+f(i+1,j-1)-f(i-1,j+1)-f(i,j+1)-f(i+1,j+1))+abs(f(i+1,j-1)+f(i+1,j)+f(i+1,j+1)-f(i-1,j-1)-f(i-1,j)-f(i-1,j+1));

                    if new_image(i, j) < T
                        new_image(i, j) = 0;
                    else
                        new_image(i, j) = 255;
                    end
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = edge_sobel(~, f, T)
            [m, n] = size(f);
            new_image = zeros(m, n);

            for i = 2:m-1
                for j = 2:n-1
                    new_image(i,j)=abs(f(i-1,j-1)+2*f(i,j-1)+f(i+1,j-1)-f(i-1,j+1)-2*f(i,j+1)-f(i+1,j+1))+abs(f(i+1,j-1)+2*f(i+1,j)+f(i+1,j+1)-f(i-1,j-1)-2*f(i-1,j)-f(i-1,j+1));
                    if new_image(i, j) < T
                        new_image(i, j) = 0;
                    else
                        new_image(i, j) = 255;
                    end
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = edge_laplacian(~, f, T)
            [m, n] = size(f);
            new_image = zeros(m, n);

            for i = 2:m-1
                for j = 2:n-1
                    new_image(i,j) = 4 * f(i, j) - f(i-1, j) - f(i+1, j) - f(i, j-1) - f(i, j+1);

                    if new_image(i, j) < T
                        new_image(i, j) = 0;
                    else
                        new_image(i, j) = 255;
                    end
                end
            end
            new_image = uint8(new_image);
        end


        function targetMask = clusterImage(~, image, K)


            hsv = rgb2hsv(image);


            h = hsv(:, :, 1);

            h(h > 330 / 360) = 0;

            training = h(:);


            startdata = linspace(0, 1, K)';


            [IDX, ~] = kmeans(training, K, 'Start', startdata);


            targetMask = reshape(IDX == 1, size(h));

        end


        function segmentedMask = interactiveRegionGrowing(app, image)


            % Convert the image to grayscale
            grayImage = rgb2gray(image);

            % Display the original image for seed selection
            figure;
            imshow(image);
            title('Select seed region for interactive region growing');

            % Allow the user to interactively select seed region
            h = imfreehand;
            mask = createMask(h);

            % Perform region growing
            segmentedMask = regionGrowing(app, grayImage, mask);

        end

        function segmentedMask = regionGrowing(app, image, seedMask)

            threshold = app.KnobFGseed.Value; 


            segmentedMask = zeros(size(image));


            [rows, cols] = find(seedMask);
            seedPoints = [rows, cols];


            for i = 1:size(seedPoints, 1)

                currentSeed = seedPoints(i, :);


                if segmentedMask(currentSeed(1), currentSeed(2)) == 0
                 
                    segmentedMask = growRegion(app, image, segmentedMask, currentSeed, threshold);
                end
            end
        end

        function segmentedMask = growRegion(~, image, segmentedMask, seedPoint, threshold)
         
            queueSize = numel(image);
            queue = zeros(2, queueSize);
            queueHead = 1;
            queueTail = 1;


            [height, width] = size(image);

         
            queue(:, queueTail) = seedPoint';
            queueTail = queueTail + 1;


            neighbors = [-1, 0; 0, -1; 0, 1; 1, 0];

            while queueHead < queueTail

                currentPixel = queue(:, queueHead);
                queueHead = queueHead + 1;

 
                if currentPixel(1) >= 1 && currentPixel(1) <= height && currentPixel(2) >= 1 && currentPixel(2) <= width
                  
                    if segmentedMask(currentPixel(1), currentPixel(2)) == 0
  
                        if abs(image(currentPixel(1), currentPixel(2)) - image(seedPoint(1), seedPoint(2))) <= threshold

                            segmentedMask(currentPixel(1), currentPixel(2)) = 1;

                         
                            for i = 1:size(neighbors, 1)
                                neighbor = currentPixel + neighbors(i, :)';

                            
                                if neighbor(1) >= 1 && neighbor(1) <= height && neighbor(2) >= 1 && neighbor(2) <= width
                                    queue(:, queueTail) = neighbor;
                                    queueTail = queueTail + 1;
                                end
                            end
                        end
                    end
                end
            end
        end


        % 将带有alpha通道的图像放到背景上作为前景
        function blend_img = blend(app, alpha_img, alpha, background_img)
            % 调整 alpha_img 的大小
            targetWidth = size(background_img, 2);
            targetHeight = size(background_img, 1);
            blend_img = imresize(alpha_img, [targetHeight, targetWidth], 'Method', 'bilinear');

            % 获取调整大小后的 alpha 通道
            resizedAlpha = imresize(alpha, [targetHeight, targetWidth], 'Method', 'bilinear');

            % 处理透明通道
            for i = 1:targetHeight
                for j = 1:targetWidth
                    if resizedAlpha(i, j) == 0
                        % 如果当前像素是透明的，将其替换为 app.ImgFGinput.ImageSource 中的对应像素
                        blend_img(i, j, :) = app.ImgFGinput.ImageSource(i, j, :);
                    end
                end
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Original
        function OriginalClicked(app, event)
            % 打开文件选择对话框，选择图片文件
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files (*.jpg, *.png, *.jpeg)'; '*.*', 'All Files (*.*)'}, ...
                                      '选择一张图片');
            if isequal(file, 0)
                disp('用户未选择任何文件');
            else
                % 如果选择了文件，构造完整的文件路径
                imagePath = fullfile(path, file);
                
                app.Original.ImageSource = imagePath;
                
                imgData = imread(app.Original.ImageSource);
                % scaleText = sprintf('%.2f \\times %.2f', k_x, k_y);
                % app.LabelRSInputSize.Text = scaleText;
                app.LabelRSInputSize.Text = "图片尺寸：$ " + int32(size(imgData, 2) ) + " \times " + int32(size(imgData, 1)) + "$";

            end
        end

        % Button down function: Tab_10
        function Tab_10ButtonDown(app, event)
            img = imread(app.Original.ImageSource);
            histogram = gray_histogram(app, rgb2gray(img));
            draw_histogram(app, histogram);
        end

        % Button down function: Tab_3
        function Tab_3ButtonDown(app, event)
            % 点击灰度之后显示右侧显示灰度图
            % 检查原始图像是否存在
            if isempty(app.Original.ImageSource)
                uialert(app.UIFigure, '没有加载图像！', '错误', 'Icon', 'error');
                return;
            end
            % 读取原始图像
            img = imread(app.Original.ImageSource);
            
            % 将图像从 RGB 转换为灰度图
            img_gray = rgb2gray(img);  % 使用 rgb2gray 而不是 im2gray
            
            % 将灰度图像转换为 RGB 格式（3 通道）
            img_gray_rgb = cat(3, img_gray, img_gray, img_gray);
            
            % 存储灰度图到 app 中的属性，并显示
            app.Image_2.ImageSource = img_gray_rgb;  % 将 RGB 图像指定为 ImageSource
            

        end

        % Button down function: Tab_11
        function Tab_11ButtonDown(app, event)
            % 实现直方图均衡化
            img = imread(app.Original.ImageSource);
            equalized_image = histogram_equalization(app, img);
            app.Image_3.ImageSource = cat(3, equalized_image, equalized_image, equalized_image);

            % img_new = imread(app.Image_3.ImageSource);
            histogram_equal = gray_histogram(app,app.Image_3.ImageSource );
            draw_histogram_2(app, histogram_equal);
        end

        % Button down function: Tab_4
        function Tab_4ButtonDown(app, event)
            app.Image_3.ImageSource = "";
            app.Image_2.ImageSource = "";
            

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           % 图像缩放
            imgData = imread(app.Original.ImageSource);
            k_x = app.SliderRSk_x.Value;
            k_y = app.SliderRSk_y.Value;
            new_image = scale(app, imgData, k_x, k_y);

            app.Image_2.ImageSource = new_image;
            % 格式化缩放倍数文本
            scaleText = sprintf('%.2f \\times %.2f', k_x, k_y);
            app.LabelRSScalek.Text = "缩放倍数：$ " + scaleText + " $ ";
            app.LabelRSInputSize_2.Text = "缩放后尺寸：$ " + int32(size(imgData, 2)  * k_x ) + " \times " + int32(size(imgData, 1) * k_y) + "$";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 图像旋转
            imgData = imread(app.Original.ImageSource);
            value = app.KnobRSrotate.Value;
            new_image = rotate(app, imgData, value);
            app.Image_3.ImageSource = new_image;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            img = imread(app.Original.ImageSource);
            imgData = rgb2gray(img);
            
            app.Image_2.ImageSource = cat(3,imgData,imgData,imgData);
            app.LabelRSInputSize_2.Text = "灰度图";
            T = round(app.Slider.Value);

            if strcmp(value,"robert算子")
                robert = edge_roberts(app, imgData, T);
                app.Image_3.ImageSource = cat(3, robert, robert, robert);
                app.LabelRSInputSize_3.Text = "robert算子边缘检测";
            elseif strcmp(value,"prewott算子")
                prewitt = edge_prewitt(app, imgData, T);
                app.Image_3.ImageSource = cat(3, prewitt, prewitt, prewitt);
                app.LabelRSInputSize_3.Text = "prewott算子边缘检测";
            elseif strcmp(value,"sobel算子")
                sobel = edge_sobel(app, imgData, T);
                app.Image_3.ImageSource = cat(3, sobel, sobel, sobel);
                app.LabelRSInputSize_3.Text = "sobel算子边缘检测";
            elseif strcmp(value,"拉普拉斯算子")
                laplacian = edge_laplacian(app, imgData, T);
                 app.Image_3.ImageSource = cat(3, laplacian, laplacian, laplacian); 
                 app.LabelRSInputSize_3.Text = "拉普拉斯算子边缘检测";
            end 
                  
          
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            % 灰度图线性增强
            img = imread(app.Original.ImageSource);
            imgData = rgb2gray(img);
            value = app.SpinnerEnhanceTan.Value;
            new_image = linear_enhance(app, imgData, value);
            app.Image_3.ImageSource = cat(3, new_image, new_image, new_image);
            app.LabelRSInputSize_3.Text = "线性灰度图像增强";

        end

        % Button down function: Tab_13
        function Tab_13ButtonDown(app, event)
            % 创建显示灰度图
            img = imread(app.Original.ImageSource);
            
            % 将图像从 RGB 转换为灰度图
            img_gray = rgb2gray(img);  % 使用 rgb2gray 而不是 im2gray
            
            % 将灰度图像转换为 RGB 格式（3 通道）
            img_gray_rgb = cat(3, img_gray, img_gray, img_gray);
            
            % 存储灰度图到 app 中的属性，并显示
            app.Image_2.ImageSource = img_gray_rgb;  % 将 RGB 图像指定为 ImageSource
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % 灰度图 非线性对数增强
            img = imread(app.Original.ImageSource);
            imgData = rgb2gray(img);
            value = app.SpinnerEnhanceLog.Value;
            new_image = log_enhance(app, imgData, value);
            app.Image_3.ImageSource = cat(3, new_image, new_image, new_image);
            app.LabelRSInputSize_3.Text = "非线性对数增强";
        end

        % Button pushed function: BtnEnhanceExp
        function BtnEnhanceExpButtonPushed(app, event)
            % 灰度图 非线性指数增强
            img = imread(app.Original.ImageSource);
            imgData = rgb2gray(img);
            a = app.SpinnerEnhanceExpa.Value;
            b = app.SpinnerEnhanceExpb.Value;
            c = app.SpinnerEnhanceExpc.Value;
            new_image = exp_enhance(app, imgData, a, b, c);
            app.Image_3.ImageSource = cat(3, new_image, new_image, new_image);
            app.LabelRSInputSize_3.Text = "非线性指数增强";
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            % 椒盐噪声
            value = app.SliderNFsaltD.Value;
            img = imread(app.Original.ImageSource);
            app.Image_2.ImageSource =  imnoise(img, 'salt & pepper', value);
            app.LabelRSInputSize_2.Text = "椒盐噪声图";
        end

        % Button pushed function: BtnNFg
        function BtnNFgButtonPushed(app, event)
            % 高斯噪声
            img = imread(app.Original.ImageSource);
            m = app.SliderNFgm.Value;    % 均值
            Var = app.SpinnerNFgVar.Value;   % 方差
            app.Image_2.ImageSource = imnoise(img,'gaussian',m, Var);
            app.LabelRSInputSize_2.Text = "高斯噪声图";
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 双边滤波
            w = round(app.SliderNFomega.Value);
            simga_s = double(app.KnobNFsigma_s.Value);
            simga_r = double(app.KnobNFsigma_r.Value);

            imgData = app.Image_2.ImageSource;

            app.Image_3.ImageSource =filter_double(app, imgData, w, simga_s, simga_r);
            app.LabelRSInputSize_3.Text = "双边滤波图像";
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % 基于模糊的平滑滤波
            value = round(app.NKnobNFmohu.Value) - 48 ;
            app.Image_3.ImageSource = filter_mohu(app, app.Image_2.ImageSource, value);
            app.LabelRSInputSize_3.Text = "基于模糊的平滑滤波";
        end

        % Button pushed function: LBPButton
        function LBPButtonPushed(app, event)
            % 进行LBP特征提取
            radius = app.SpinnerNFgVar_2.Value;
            neighbors = app.SpinnerNFgVar_3.Value;
            img = imread(app.Original.ImageSource);
            targetImg = app.Image_2.ImageSource;

            imgData = rgb2gray(img);
            [~, lbp_image] = lbp_histogram(app,imgData, radius, neighbors);
            
            % lbp_image_rgb = repmat(lbp_image, [1, 1, 3]);
            lbp_image_rgb = cat(3,lbp_image,lbp_image,lbp_image);
            
            % 对提取出来的目标进行特征检测
            targetImgData = rgb2gray(targetImg);
            [~, lbp_target_image] = lbp_histogram(app,targetImgData, radius, neighbors);
            
            % lbp_image_rgb = repmat(lbp_image, [1, 1, 3]);
            lbp_target_image_rgb = cat(3,lbp_target_image,lbp_target_image,lbp_target_image);


            % 设置图像源
            app.Image_2.ImageSource = uint8(lbp_target_image_rgb);
            app.LabelRSInputSize_2.Text = "对提取目标进行LBP特征提取";
            app.Image_3.ImageSource = uint8(lbp_image_rgb);
            app.LabelRSInputSize_3.Text = "LBP 特征提取图";
            % app.Image_3.ImageSource = lbp_image;
        end

        % Button pushed function: HOGButton
        function HOGButtonPushed(app, event)
            cell_size = app.SpinnerNFgVar_4.Value;
            block_size = app.SpinnerNFgVar_5.Value;
            bins = app.SpinnerNFgVar_6.Value;
            targetImg = app.Image_2.ImageSource;
            img = imread(app.Original.ImageSource);
            imgData = rgb2gray(img);
            imgData = double(imgData);
           % 调用HOG特征提取函数
            [~, hog_image] = hog_feature(app,imgData, cell_size, block_size, bins);
            
            % 将HOG特征图像显示到界面上
            % 将灰度图像转换为RGB格式
            hog_image_rgb = cat(3,hog_image,hog_image,hog_image);
            
             % 对提取出来的目标进行特征检测
            targetImgData = rgb2gray(targetImg);
            [~, lbp_target_image] = hog_feature(app,targetImgData, cell_size, block_size, bins);
            
            % lbp_image_rgb = repmat(lbp_image, [1, 1, 3]);
            lbp_target_image_rgb = cat(3,lbp_target_image,lbp_target_image,lbp_target_image);


            app.Image_2.ImageSource = uint8(lbp_target_image_rgb);
            app.LabelRSInputSize_2.Text = "对提取目标进行HOG特征提取";
            % hog_image_rgb = cat(3, hog_image, hog_image, hog_image);  % 转为RGB
            app.Image_3.ImageSource = uint8(hog_image_rgb);  % 转为uint8  
            
            % 设置图像源
            % app.Image_3.ImageSource = hog_image_rgb;
            app.LabelRSInputSize_3.Text = "HOG 特征提取图";

        end

        % Button pushed function: BtnFGOstu
        function BtnFGOstuButtonPushed(app, event)
             % 转换为灰度图像
            imgData = imread(app.Original.ImageSource);
            grayImage = rgb2gray(imgData);


            % 使用Ostu最大类间方差法，进行阈值分割（可根据实际情况调整阈值）
            threshold = graythresh(grayImage);

            app.LabelFGOstu.Text = threshold * 255 + "";

            binaryImage = imbinarize(grayImage, threshold);

            % 对二值图像进行后处理（去除小的区域）
            binaryImage = bwareaopen(binaryImage, 1000);
            
            % colorMask = cat(3, binaryImage, binaryImage, binaryImage);

            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end

            % 将彩色蒙版叠加到原始图片上
            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;

            % 将这些像素设置为白色
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.Image_2.ImageSource = segmentedResult;
            app.LabelRSInputSize_2.Text = "Ostu最大类间方差法目标提取";

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % 打开文件选择对话框，选择图片文件
            % 显示需要检测的目标
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files (*.jpg, *.png, *.jpeg)'; '*.*', 'All Files (*.*)'}, ...
                                      '选择一张图片');
            if isequal(file, 0)
                disp('用户未选择任何文件');
            else
                % 如果选择了文件，构造完整的文件路径
                imagePath = fullfile(path, file);
                
                app.Image.ImageSource = imagePath;
                
                % imgData = imread(app.Original.ImageSource);
                % scaleText = sprintf('%.2f \\times %.2f', k_x, k_y);
                % app.LabelRSInputSize.Text = scaleText;
                % app.LabelRSInputSize.Text = "图片尺寸：$ " + int32(size(imgData, 2) ) + " \times " + int32(size(imgData, 1)) + "$";
            end    
        end

        % Value changed function: KnobFGt
        function KnobFGtValueChanged(app, event)
            threshold = app.KnobFGt.Value;
            imgData = imread(app.Original.ImageSource);
            grayImage = rgb2gray(imgData);

            app.LabelFGt.Text = round(threshold) + "";

            binaryImage = imbinarize(grayImage, threshold / 255);

            % 对二值图像进行后处理（去除小的区域）
            binaryImage = bwareaopen(binaryImage, 1000);

            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end
            % 将彩色蒙版叠加到原始图片上
            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;

            % 将这些像素设置为白色
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.Image_2.ImageSource = segmentedResult;
             app.LabelRSInputSize_2.Text = "手动阈值分割法目标提取";
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            k = app.SpinnerFGk.Value;
            % 转换为灰度图像
            imgData =  imread(app.Original.ImageSource);


            binaryImage = clusterImage(app, imgData, k);
            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end

            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;

            % 将这些像素设置为白色
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.Image_2.ImageSource = segmentedResult;
             app.LabelRSInputSize_2.Text = "K聚类法目标提取";
        end

        % Button pushed function: Btnseed
        function BtnseedButtonPushed(app, event)
            imgData =  imread(app.Original.ImageSource);
            binaryImage =   interactiveRegionGrowing(app, imgData);
            

            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end

            colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            % 将彩色蒙版叠加到原始图片上
            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;
            % 将这些像素设置为白色

            indices(end) = 1;
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.Image_2.ImageSource = segmentedResult;
            app.LabelRSInputSize_2.Text = "区域生长法目标提取";
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            kind = app.DropDown.Value;
            url = app.Original.ImageSource;
            res = "Black_footed_Albatross";
            if strcmp(kind,"vgg")
                py.importlib.import_module('vgg');
                res = py.vgg.predict(url);
            elseif strcmp(kind,"EfficientNet")
                py.importlib.import_module('efficientNet');
                res = py.efficientNet.main(url);
            end
            
            % 将 Python 字符串转换为 MATLAB 字符串
            res_str = string(res);  % 或者使用 string(res)
            
            % 将结果显示在 MATLAB GUI 上
            app.label.Text = res_str;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {661, 661, 661};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {661, 661};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x', 316};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Original = uiimage(app.LeftPanel);
            app.Original.ImageClickedFcn = createCallbackFcn(app, @OriginalClicked, true);
            app.Original.ImageSource = fullfile(pathToMLAPP, '1.jpg');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '类别预测模型';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'VGG', 'EfficientNet'};
            app.DropDown.Value = 'EfficientNet';
            app.LabelRSInputSize = uilabel(app.LeftPanel);
            app.LabelRSInputSize.Interpreter = 'latex';
            app.LabelRSInputSize.Text = '';
            app.ImgFGbin = uiimage(app.LeftPanel);
            app.Label_16 = uilabel(app.LeftPanel);
            app.Label_16.Text = '分割二值化蒙版';
            app.FGreverse = uicheckbox(app.LeftPanel);
            app.FGreverse.Text = '蒙版取反';
            app.Button_9 = uibutton(app.LeftPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '类别预测';
            app.label = uilabel(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '椒盐噪声';
            app.Label_5 = uilabel(app.Tab);
            app.Label_5.Text = '噪声密度';
            app.SliderNFsaltD = uislider(app.Tab);
            app.SliderNFsaltD.Limits = [0 1];
            app.Button_5 = uibutton(app.Tab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '椒盐噪声';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '高斯噪声';
            app.BtnNFg = uibutton(app.Tab_2, 'push');
            app.BtnNFg.ButtonPushedFcn = createCallbackFcn(app, @BtnNFgButtonPushed, true);
            app.BtnNFg.Text = '显示高斯噪声';
            app.Label_6 = uilabel(app.Tab_2);
            app.Label_6.Text = '均值';
            app.SliderNFgm = uislider(app.Tab_2);
            app.SliderNFgm.Limits = [-1 1];
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '方差';
            app.SpinnerNFgVar = uispinner(app.Tab_2);
            app.SpinnerNFgVar.Step = 0.01;
            app.SpinnerNFgVar.Limits = [0 2];
            app.Tab_18 = uitab(app.TabGroup);
            app.Tab_18.Title = '空域滤波';
            app.SliderNFomega = uislider(app.Tab_18);
            app.SliderNFomega.Limits = [1 30];
            app.SliderNFomega.Tooltip = {' 表示双边滤波器的窗口大小'};
            app.SliderNFomega.Value = 10;
            app.omegasigma_ssigma_rLabel = uilabel(app.Tab_18);
            app.omegasigma_ssigma_rLabel.Interpreter = 'latex';
            app.omegasigma_ssigma_rLabel.Text = '$ \omega, \sigma_s, \sigma_r  $  取值越大，图像平滑作用越强';
            app.KnobNFsigma_s = uiknob(app.Tab_18, 'continuous');
            app.KnobNFsigma_s.Limits = [2 10];
            app.KnobNFsigma_s.Tooltip = {'决定滤波窗口中的像素个数'};
            app.KnobNFsigma_s.Value = 5;
            app.KnobNFsigma_r = uiknob(app.Tab_18, 'continuous');
            app.KnobNFsigma_r.Limits = [0 1];
            app.KnobNFsigma_r.Tooltip = {'控制灰度差值多大的点能影响中心点的像素值'};
            app.KnobNFsigma_r.Value = 0.25;
            app.omegaLabel = uilabel(app.Tab_18);
            app.omegaLabel.Interpreter = 'latex';
            app.omegaLabel.Text = '$\omega$';
            app.sigma_sKnobLabel = uilabel(app.Tab_18);
            app.sigma_sKnobLabel.Interpreter = 'latex';
            app.sigma_sKnobLabel.Text = '$\sigma_s$';
            app.sigma_rKnobLabel = uilabel(app.Tab_18);
            app.sigma_rKnobLabel.Interpreter = 'latex';
            app.sigma_rKnobLabel.Text = '$\sigma_r$';
            app.Button_6 = uibutton(app.Tab_18, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '双边滤波';
            app.Tab_19 = uitab(app.TabGroup);
            app.Tab_19.Title = '频域滤波';
            app.Label_8 = uilabel(app.Tab_19);
            app.Label_8.Text = '';
            app.Button_7 = uibutton(app.Tab_19, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '基于模糊的平滑滤波';
            app.NKnobLabel = uilabel(app.Tab_19);
            app.NKnobLabel.Interpreter = 'latex';
            app.NKnobLabel.Text = '邻域内点的数目 $N$';
            app.NKnobNFmohu = uiknob(app.Tab_19, 'discrete');
            app.NKnobNFmohu.Items = {'3', '5', '7', '9'};
            app.NKnobNFmohu.Value = '3';
            app.TabGroup2 = uitabgroup(app.CenterPanel);
            app.Tab_3 = uitab(app.TabGroup2);
            app.Tab_3.Title = '灰度';
            app.Tab_3.ButtonDownFcn = createCallbackFcn(app, @Tab_3ButtonDown, true);
            app.TabGroup4 = uitabgroup(app.Tab_3);
            app.Tab_10 = uitab(app.TabGroup4);
            app.Tab_10.Title = '灰度直方图';
            app.Tab_10.ButtonDownFcn = createCallbackFcn(app, @Tab_10ButtonDown, true);
            app.UIAxesGrayHistogramHistgram = uiaxes(app.Tab_10);
            title(app.UIAxesGrayHistogramHistgram, '灰度直方图')
            xlabel(app.UIAxesGrayHistogramHistgram, '灰度值')
            ylabel(app.UIAxesGrayHistogramHistgram, '频度')
            zlabel(app.UIAxesGrayHistogramHistgram, 'Z')
            app.Tab_11 = uitab(app.TabGroup4);
            app.Tab_11.Title = '直方图均衡化';
            app.Tab_11.ButtonDownFcn = createCallbackFcn(app, @Tab_11ButtonDown, true);
            app.UIAxesGrayHistogramHistgram_2 = uiaxes(app.Tab_11);
            title(app.UIAxesGrayHistogramHistgram_2, '均衡化直方图')
            xlabel(app.UIAxesGrayHistogramHistgram_2, '灰度值')
            ylabel(app.UIAxesGrayHistogramHistgram_2, '频度')
            zlabel(app.UIAxesGrayHistogramHistgram_2, 'Z')
            app.Tab_12 = uitab(app.TabGroup4);
            app.Tab_12.Title = '直方图匹配';
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '图像变换';
            app.Tab_4.ButtonDownFcn = createCallbackFcn(app, @Tab_4ButtonDown, true);
            app.TabGroup3 = uitabgroup(app.Tab_4);
            app.Tab_7 = uitab(app.TabGroup3);
            app.Tab_7.Title = '缩放';
            app.Button = uibutton(app.Tab_7, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '缩放';
            app.LabelRSScalek = uilabel(app.Tab_7);
            app.LabelRSScalek.Interpreter = 'latex';
            app.LabelRSScalek.Text = '缩放倍数：$0.00 \times 0.00  $';
            app.k_xSliderLabel = uilabel(app.Tab_7);
            app.k_xSliderLabel.Text = 'k_x';
            app.SliderRSk_x = uislider(app.Tab_7);
            app.SliderRSk_x.Limits = [0.03 5.03];
            app.SliderRSk_x.Value = 1;
            app.k_yLabel = uilabel(app.Tab_7);
            app.k_yLabel.Text = 'k_y';
            app.SliderRSk_y = uislider(app.Tab_7);
            app.SliderRSk_y.Limits = [0.03 5.03];
            app.SliderRSk_y.Value = 1;
            app.Tab_9 = uitab(app.TabGroup3);
            app.Tab_9.Title = '旋转';
            app.Label_2 = uilabel(app.Tab_9);
            app.Label_2.Text = '旋转';
            app.KnobRSrotate = uiknob(app.Tab_9, 'continuous');
            app.KnobRSrotate.Limits = [-180 180];
            app.Button_2 = uibutton(app.Tab_9, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '旋转';
            app.Tab_13 = uitab(app.TabGroup2);
            app.Tab_13.Title = '灰度图像的对比度增强';
            app.Tab_13.ButtonDownFcn = createCallbackFcn(app, @Tab_13ButtonDown, true);
            app.TabGroup7 = uitabgroup(app.Tab_13);
            app.Tab_14 = uitab(app.TabGroup7);
            app.Tab_14.Title = '线性增强';
            app.gxytanalphacdotfxyLabel = uilabel(app.Tab_14);
            app.gxytanalphacdotfxyLabel.Interpreter = 'latex';
            app.gxytanalphacdotfxyLabel.Text = '$$g(x, y) = tan\alpha \cdot f(x, y)  $$';
            app.Button_3 = uibutton(app.Tab_14, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '线性增强';
            app.tanSpinnerLabel = uilabel(app.Tab_14);
            app.tanSpinnerLabel.Interpreter = 'latex';
            app.tanSpinnerLabel.Text = 'tanα = ';
            app.SpinnerEnhanceTan = uispinner(app.Tab_14);
            app.SpinnerEnhanceTan.Step = 0.05;
            app.SpinnerEnhanceTan.Limits = [0 5];
            app.SpinnerEnhanceTan.Value = 2;
            app.Tab_15 = uitab(app.TabGroup7);
            app.Tab_15.Title = '对数增强';
            app.gxyccdotlogfxy1Label = uilabel(app.Tab_15);
            app.gxyccdotlogfxy1Label.Interpreter = 'latex';
            app.gxyccdotlogfxy1Label.Text = '$$g(x, y) = c \cdot log [f(x, y) + 1]$$';
            app.cLabel = uilabel(app.Tab_15);
            app.cLabel.Interpreter = 'latex';
            app.cLabel.Text = 'c = ';
            app.SpinnerEnhanceLog = uispinner(app.Tab_15);
            app.SpinnerEnhanceLog.Step = 2;
            app.SpinnerEnhanceLog.Limits = [1 200];
            app.SpinnerEnhanceLog.Value = 46;
            app.Button_4 = uibutton(app.Tab_15, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '对数增强';
            app.Tab_16 = uitab(app.TabGroup7);
            app.Tab_16.Title = '指数增强';
            app.gxybccdotfxyalpha1Label = uilabel(app.Tab_16);
            app.gxybccdotfxyalpha1Label.Interpreter = 'latex';
            app.gxybccdotfxyalpha1Label.Text = '$$g(x, y) = b ^ {  c \cdot [f(x, y) - \alpha]  } - 1$$';
            app.BtnEnhanceExp = uibutton(app.Tab_16, 'push');
            app.BtnEnhanceExp.ButtonPushedFcn = createCallbackFcn(app, @BtnEnhanceExpButtonPushed, true);
            app.BtnEnhanceExp.Text = '指数增强';
            app.cLabel_2 = uilabel(app.Tab_16);
            app.cLabel_2.Interpreter = 'latex';
            app.cLabel_2.Text = 'b = ';
            app.SpinnerEnhanceExpb = uispinner(app.Tab_16);
            app.SpinnerEnhanceExpb.Step = 0.1;
            app.SpinnerEnhanceExpb.Limits = [0 10];
            app.SpinnerEnhanceExpb.Value = 2.818;
            app.cLabel_3 = uilabel(app.Tab_16);
            app.cLabel_3.Interpreter = 'latex';
            app.cLabel_3.Text = 'c = ';
            app.SpinnerEnhanceExpc = uispinner(app.Tab_16);
            app.SpinnerEnhanceExpc.Step = 0.01;
            app.SpinnerEnhanceExpc.Limits = [0 1];
            app.SpinnerEnhanceExpc.Value = 0.05;
            app.cLabel_4 = uilabel(app.Tab_16);
            app.cLabel_4.Interpreter = 'latex';
            app.cLabel_4.Text = '$ \alpha $ = ';
            app.SpinnerEnhanceExpa = uispinner(app.Tab_16);
            app.SpinnerEnhanceExpa.Step = 5;
            app.SpinnerEnhanceExpa.Limits = [0 255];
            app.SpinnerEnhanceExpa.Value = 5;
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '边缘提取';
            app.Label_3 = uilabel(app.Tab_5);
            app.Label_3.Text = '算子选择';
            app.DropDown_2 = uidropdown(app.Tab_5);
            app.DropDown_2.Items = {'robert算子', 'prewott算子', 'sobel算子', '拉普拉斯算子'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'robert算子';
            app.Label_4 = uilabel(app.Tab_5);
            app.Label_4.Text = '阈值选择';
            app.Slider = uislider(app.Tab_5);
            app.Slider.Limits = [0 255];
            app.Tab_17 = uitab(app.TabGroup2);
            app.Tab_17.Title = '目标提取';
            app.Image = uiimage(app.Tab_17);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.TabGroup8 = uitabgroup(app.Tab_17);
            app.Tab_20 = uitab(app.TabGroup8);
            app.Tab_20.Title = '最大类间方差法';
            app.LabelFGOstu = uilabel(app.Tab_20);
            app.LabelFGOstu.Text = '000.000';
            app.OtsuLabel = uilabel(app.Tab_20);
            app.OtsuLabel.Text = '采用Otsu方法计算的图像全局最佳阈值为：';
            app.BtnFGOstu = uibutton(app.Tab_20, 'push');
            app.BtnFGOstu.ButtonPushedFcn = createCallbackFcn(app, @BtnFGOstuButtonPushed, true);
            app.BtnFGOstu.Text = 'Ostu最大类间方差法进行阈值分隔';
            app.Tab_21 = uitab(app.TabGroup8);
            app.Tab_21.Title = '手动阈值分割';
            app.LabelFGt = uilabel(app.Tab_21);
            app.LabelFGt.Text = '000.000';
            app.Label_15 = uilabel(app.Tab_21);
            app.Label_15.Text = '当前分割阈值：';
            app.ThresholdLabel = uilabel(app.Tab_21);
            app.ThresholdLabel.Text = 'Threshold';
            app.KnobFGt = uiknob(app.Tab_21, 'continuous');
            app.KnobFGt.Limits = [0 255];
            app.KnobFGt.ValueChangedFcn = createCallbackFcn(app, @KnobFGtValueChanged, true);
            app.kTab = uitab(app.TabGroup8);
            app.kTab.Title = 'k聚类';
            app.Button_8 = uibutton(app.kTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '聚类分割';
            app.KSpinnerLabel = uilabel(app.kTab);
            app.KSpinnerLabel.Text = 'K';
            app.SpinnerFGk = uispinner(app.kTab);
            app.SpinnerFGk.Limits = [2 5];
            app.SpinnerFGk.Value = 2;
            app.Tab_22 = uitab(app.TabGroup8);
            app.Tab_22.Title = '区域生长法';
            app.Btnseed = uibutton(app.Tab_22, 'push');
            app.Btnseed.ButtonPushedFcn = createCallbackFcn(app, @BtnseedButtonPushed, true);
            app.Btnseed.Text = '区域生长法图像分割';
            app.KnobFGseed = uiknob(app.Tab_22, 'continuous');
            app.KnobFGseed.Limits = [0 255];
            app.Label_14 = uilabel(app.Tab_17);
            app.Label_14.Text = '需要提取的目标';
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '特征提取';
            app.TabGroup6 = uitabgroup(app.Tab_6);
            app.LBPTab = uitab(app.TabGroup6);
            app.LBPTab.Title = 'LBP';
            app.Label_9 = uilabel(app.LBPTab);
            app.Label_9.Text = '半径';
            app.SpinnerNFgVar_2 = uispinner(app.LBPTab);
            app.SpinnerNFgVar_2.Limits = [1 2];
            app.SpinnerNFgVar_2.Value = 1;
            app.Label_10 = uilabel(app.LBPTab);
            app.Label_10.Text = '邻居数量';
            app.SpinnerNFgVar_3 = uispinner(app.LBPTab);
            app.SpinnerNFgVar_3.Step = 8;
            app.SpinnerNFgVar_3.Limits = [8 16];
            app.SpinnerNFgVar_3.Value = 8;
            app.LBPButton = uibutton(app.LBPTab, 'push');
            app.LBPButton.ButtonPushedFcn = createCallbackFcn(app, @LBPButtonPushed, true);
            app.LBPButton.Text = 'LBP特征提取';
            app.HOGTab = uitab(app.TabGroup6);
            app.HOGTab.Title = 'HOG';
            app.HOGButton = uibutton(app.HOGTab, 'push');
            app.HOGButton.ButtonPushedFcn = createCallbackFcn(app, @HOGButtonPushed, true);
            app.HOGButton.Text = 'HOG特征提取';
            app.Label_11 = uilabel(app.HOGTab);
            app.Label_11.Text = 'cell_size';
            app.SpinnerNFgVar_4 = uispinner(app.HOGTab);
            app.SpinnerNFgVar_4.Step = 2;
            app.SpinnerNFgVar_4.Limits = [6 8];
            app.SpinnerNFgVar_4.Value = 6;
            app.Label_12 = uilabel(app.HOGTab);
            app.Label_12.Text = 'bolck_size';
            app.SpinnerNFgVar_5 = uispinner(app.HOGTab);
            app.SpinnerNFgVar_5.Limits = [1 2];
            app.SpinnerNFgVar_5.Value = 2;
            app.Label_13 = uilabel(app.HOGTab);
            app.Label_13.Text = 'bins';
            app.SpinnerNFgVar_6 = uispinner(app.HOGTab);
            app.SpinnerNFgVar_6.Limits = [4 16];
            app.SpinnerNFgVar_6.Value = 9;
            app.RightPanel = uipanel(app.GridLayout);
            app.Image_2 = uiimage(app.RightPanel);
            app.Image_3 = uiimage(app.RightPanel);
            app.LabelRSInputSize_2 = uilabel(app.RightPanel);
            app.LabelRSInputSize_2.Interpreter = 'latex';
            app.LabelRSInputSize_2.Text = '';
            app.LabelRSInputSize_3 = uilabel(app.RightPanel);
            app.LabelRSInputSize_3.Interpreter = 'latex';
            app.LabelRSInputSize_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end